classdef group31 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ComputeButton          matlab.ui.control.Button
        ClassesPanel           matlab.ui.container.Panel
        HzSwitchLabel          matlab.ui.control.Label
        HzSwitch               matlab.ui.control.Switch
        HzSwitch_2Label        matlab.ui.control.Label
        HzSwitch_2             matlab.ui.control.Switch
        HzSwitch_3Label        matlab.ui.control.Label
        HzSwitch_3             matlab.ui.control.Switch
        HzSwitch_4Label        matlab.ui.control.Label
        HzSwitch_4             matlab.ui.control.Switch
        DistanceMetricPanel    matlab.ui.container.Panel
        RiemannSwitchLabel     matlab.ui.control.Label
        RiemannSwitch          matlab.ui.control.Switch
        kullbackSwitchLabel    matlab.ui.control.Label
        kullbackSwitch         matlab.ui.control.Switch
        logeuclidSwitchLabel   matlab.ui.control.Label
        logeuclidSwitch        matlab.ui.control.Switch
        opttranspSwitchLabel   matlab.ui.control.Label
        opttranspSwitch        matlab.ui.control.Switch
        ldSwitchLabel          matlab.ui.control.Label
        ldSwitch               matlab.ui.control.Switch
        EucleadianSwitchLabel  matlab.ui.control.Label
        EucleadianSwitch       matlab.ui.control.Switch
    end


    properties (Access = private)
        nbrClasses
        SubjectMean
        classes
        ClassMean
        type% Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function start(app)
            TrainPath='..\..\DataSet\From the Internet\4\subject10\Training GDF\*.gdf';
            nbrClasses=[13,17,21];
            [ClassMean,SubjectMean,debug,timestamp,classes,TanSpace]=OfflineAlgo(TrainPath,nbrClasses);
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            TestPath='..\..\DataSet\From the Internet\4\subject10\Testing GDF\*.gdf';
            test=SubjectEEG(TestPath,nbrClasses);
            Ctest=EEGtoCov(test,classes);

   
            type=["riemann","kullback"];
            plotOffline(nbrClasses,SubjectMean,Ctest,type,'Without Outlier Removal');
        end

        % Value changed function: HzSwitch
        function HzSwitchValueChanged(app, event)
            first = app.HzSwitch.Value;
            
        end

        % Value changed function: HzSwitch_2
        function HzSwitch_2ValueChanged(app, event)
            second = app.HzSwitch_2.Value;
            
        end

        % Value changed function: HzSwitch_3
        function HzSwitch_3ValueChanged(app, event)
            third = app.HzSwitch_3.Value;
            
        end

        % Value changed function: HzSwitch_4
        function HzSwitch_4ValueChanged(app, event)
            fourth = app.HzSwitch_4.Value;
            
        end

        % Value changed function: RiemannSwitch
        function RiemannSwitchValueChanged(app, event)
            riemann = app.RiemannSwitch.Value;
            
        end

        % Value changed function: kullbackSwitch
        function kullbackSwitchValueChanged(app, event)
            kullback = app.kullbackSwitch.Value;
            
        end

        % Value changed function: logeuclidSwitch
        function logeuclidSwitchValueChanged(app, event)
            logeuclid = app.logeuclidSwitch.Value;
            
        end

        % Value changed function: opttranspSwitch
        function opttranspSwitchValueChanged(app, event)
            opttransp = app.opttranspSwitch.Value;
            
        end

        % Value changed function: ldSwitch
        function ldSwitchValueChanged(app, event)
            ld = app.ldSwitch.Value;
            
        end

        % Value changed function: EucleadianSwitch
        function EucleadianSwitchValueChanged(app, event)
            eucleadian = app.EucleadianSwitch.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.ClassesPanel = uipanel(app.UIFigure);
            app.ClassesPanel.Title = 'Classes';
            app.HzSwitchLabel = uilabel(app.ClassesPanel);
            app.HzSwitchLabel.Text = '0 Hz';
            app.HzSwitch = uiswitch(app.ClassesPanel, 'slider');
            app.HzSwitch.ValueChangedFcn = createCallbackFcn(app, @HzSwitchValueChanged, true);
            app.HzSwitch_2Label = uilabel(app.ClassesPanel);
            app.HzSwitch_2Label.Text = '13 Hz';
            app.HzSwitch_2 = uiswitch(app.ClassesPanel, 'slider');
            app.HzSwitch_2.ValueChangedFcn = createCallbackFcn(app, @HzSwitch_2ValueChanged, true);
            app.HzSwitch_3Label = uilabel(app.ClassesPanel);
            app.HzSwitch_3Label.Text = '17 Hz';
            app.HzSwitch_3 = uiswitch(app.ClassesPanel, 'slider');
            app.HzSwitch_3.ValueChangedFcn = createCallbackFcn(app, @HzSwitch_3ValueChanged, true);
            app.HzSwitch_4Label = uilabel(app.ClassesPanel);
            app.HzSwitch_4Label.Text = '21 Hz';
            app.HzSwitch_4 = uiswitch(app.ClassesPanel, 'slider');
            app.HzSwitch_4.ValueChangedFcn = createCallbackFcn(app, @HzSwitch_4ValueChanged, true);
            app.DistanceMetricPanel = uipanel(app.UIFigure);
            app.DistanceMetricPanel.Title = 'Distance Metric';
            app.RiemannSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.RiemannSwitchLabel.Text = 'Riemann';
            app.RiemannSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.RiemannSwitch.ValueChangedFcn = createCallbackFcn(app, @RiemannSwitchValueChanged, true);
            app.kullbackSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.kullbackSwitchLabel.Text = 'kullback';
            app.kullbackSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.kullbackSwitch.ValueChangedFcn = createCallbackFcn(app, @kullbackSwitchValueChanged, true);
            app.logeuclidSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.logeuclidSwitchLabel.Text = 'logeuclid';
            app.logeuclidSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.logeuclidSwitch.ValueChangedFcn = createCallbackFcn(app, @logeuclidSwitchValueChanged, true);
            app.opttranspSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.opttranspSwitchLabel.Text = 'opttransp';
            app.opttranspSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.opttranspSwitch.ValueChangedFcn = createCallbackFcn(app, @opttranspSwitchValueChanged, true);
            app.ldSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.ldSwitchLabel.Text = 'ld';
            app.ldSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.ldSwitch.ValueChangedFcn = createCallbackFcn(app, @ldSwitchValueChanged, true);
            app.EucleadianSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.EucleadianSwitchLabel.Text = 'Eucleadian';
            app.EucleadianSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.EucleadianSwitch.ValueChangedFcn = createCallbackFcn(app, @EucleadianSwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = group31

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end