classdef group31FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ClassesPanel                 matlab.ui.container.Panel
        HzSwitchLabel                matlab.ui.control.Label
        HzSwitch                     matlab.ui.control.Switch
        HzSwitch_2Label              matlab.ui.control.Label
        HzSwitch_2                   matlab.ui.control.Switch
        HzSwitch_3Label              matlab.ui.control.Label
        HzSwitch_3                   matlab.ui.control.Switch
        HzSwitch_4Label              matlab.ui.control.Label
        HzSwitch_4                   matlab.ui.control.Switch
        GROUP31TextAreaLabel         matlab.ui.control.Label
        GROUP31TextArea              matlab.ui.control.TextArea
        ResetButton                  matlab.ui.control.Button
        OfflineAnalysisPanel         matlab.ui.container.Panel
        DistanceMetricPanel          matlab.ui.container.Panel
        RiemannSwitchLabel           matlab.ui.control.Label
        RiemannSwitch                matlab.ui.control.Switch
        kullbackSwitchLabel          matlab.ui.control.Label
        kullbackSwitch               matlab.ui.control.Switch
        logeuclidSwitchLabel         matlab.ui.control.Label
        logeuclidSwitch              matlab.ui.control.Switch
        opttranspSwitchLabel         matlab.ui.control.Label
        opttranspSwitch              matlab.ui.control.Switch
        ldSwitchLabel                matlab.ui.control.Label
        ldSwitch                     matlab.ui.control.Switch
        EucleadianSwitchLabel        matlab.ui.control.Label
        EucleadianSwitch             matlab.ui.control.Switch
        OnlineAnalysisPanel          matlab.ui.container.Panel
        WindowLengthEditFieldLabel   matlab.ui.control.Label
        WindowLengthEditField        matlab.ui.control.NumericEditField
        WindowSpacingEditFieldLabel  matlab.ui.control.Label
        WindowSpacingEditField       matlab.ui.control.NumericEditField
        NumberEditFieldLabel         matlab.ui.control.Label
        NumberEditField              matlab.ui.control.NumericEditField
        ofEpochsSliderLabel          matlab.ui.control.Label
        ofEpochsSlider               matlab.ui.control.Slider
        AnalysisButtonGroup          matlab.ui.container.ButtonGroup
        OfflineButton                matlab.ui.control.ToggleButton
        OnlineButton                 matlab.ui.control.ToggleButton
        ComputeButton                matlab.ui.control.Button
    end


    properties (Access = private)
        
        selectedButton;
        
        nbrClasses;
        SubjectMean;
        classes;
        ClassMean;
        type;
        
        first;
        second;
        third;
        fourth;
        
        riemann;
        kullback;
        logeuclid;
        opttransp;
        ld;
        eucleadian;
        
        hcpMean;
        hcp1Mean;
        
        w;
        dn;
        D;
        
        % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function start(app)
            TrainPath='..\..\DataSet\From the Internet\4\subject10\Training GDF\*.gdf';
            app.nbrClasses=[0,13,17,21];
            [app.ClassMean, app.SubjectMean, debug, timestamp, app.classes,TanSpace]= OfflineAlgo(TrainPath, app.nbrClasses);
             
        end

        % Selection changed function: AnalysisButtonGroup
        function AnalysisButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.AnalysisButtonGroup.SelectedObject;
            
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            %OFFLINE ANALYSIS PART %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            if(app.OfflineButton.Value)
               
                app.nbrClasses=[];
                app.hcpMean=[];
                app.hcp1Mean=[];
                app.classes=[];
                if(app.first)
                    app.nbrClasses= [0];
                    app.classes= [33024];
                    app.hcpMean= [app.SubjectMean(1)];
                    app.hcp1Mean= [app.ClassMean(1)];
                end
                if(app.second)
                    app.nbrClasses= [app.nbrClasses, 13];
                    app.classes= [app.classes, 33025];
                    app.hcpMean=[app.hcpMean, app.SubjectMean(2)];
                    app.hcp1Mean= [app.hcp1Mean, app.ClassMean(2)];
                end
                if(app.third)
                    app.nbrClasses= [app.nbrClasses, 17];
                    app.classes= [app.classes, 33026];
                    app.hcpMean=[app.hcpMean, app.SubjectMean(3)];
                    app.hcp1Mean= [app.hcp1Mean, app.ClassMean(3)];
                end
                if(app.fourth)
                    app.nbrClasses= [app.nbrClasses, 21];
                    app.classes= [app.classes, 33027];
                    app.hcpMean=[app.hcpMean, app.SubjectMean(4)];
                    app.hcp1Mean= [app.hcp1Mean, app.ClassMean(4)];
                end


                app.type=[];
                if(app.riemann)
                    app.type= ["riemann"];
                end
                if(app.kullback)
                    app.type= [app.type, "kullback"];
                end
                if(app.logeuclid)
                    app.type= [app.type, "logeuclid"];
                end
                if(app.opttransp)
                    app.type= [app.type, "opttransp"];
                end
                if(app.ld)
                    app.type= [app.type, "ld"];
                end
                if(app.eucleadian)
                    app.type= [app.type, ""];
                end
                
                TestPath='..\..\DataSet\From the Internet\4\subject10\Testing GDF\*.gdf';
                test=SubjectEEG(TestPath,app.nbrClasses);
                Ctest=EEGtoCov(test,app.classes);
                
                plotOffline(app.nbrClasses, app.hcpMean, Ctest, app.type,'Without Outlier Removal');
                plotConfuse(app.nbrClasses, app.hcpMean,Ctest, app.type,'Confusion Matrix');
            end
            
            % ONLINE ANALYSIS PART%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
           if(app.OnlineButton.Value)
               
                app.nbrClasses=[];
                app.hcpMean=[];
                app.hcp1Mean=[];
                app.classes=[];
                if(app.first)
                    app.nbrClasses= [0];
                    app.classes= [33024];
                    app.hcpMean= [app.SubjectMean(1)];
                    app.hcp1Mean= [app.ClassMean(1)];
                end
                if(app.second)
                    app.nbrClasses= [app.nbrClasses, 13];
                    app.classes= [app.classes, 33025];
                    app.hcpMean=[app.hcpMean, app.SubjectMean(2)];
                    app.hcp1Mean= [app.hcp1Mean, app.ClassMean(2)];
                end
                if(app.third)
                    app.nbrClasses= [app.nbrClasses, 17];
                    app.classes= [app.classes, 33026];
                    app.hcpMean=[app.hcpMean, app.SubjectMean(3)];
                    app.hcp1Mean= [app.hcp1Mean, app.ClassMean(3)];
                end
                if(app.fourth)
                    app.nbrClasses= [app.nbrClasses, 21];
                    app.classes= [app.classes, 33027];
                    app.hcpMean=[app.hcpMean, app.SubjectMean(4)];
                    app.hcp1Mean= [app.hcp1Mean, app.ClassMean(4)];
                end
                
%                             TestPath='..\..\DataSet\From the Internet\4\subject10\Algo3\*.gdf';
%                             windw=[app.w, app.dn, app.D];
%                             thresh=0.6; 
%                             [Xr, hand]=SubEEG(TestPath, windw);
%                             Cr=PlainEEG2Cov(Xr);  
%                             [Krn,yes]=Prediction(app.SubjectMean, Cr, app.classes,'',windw,thresh);
%                             [target, K]=Labeler(timestamp,hand, app.classes,Krn);                           
%                             [t,o]=confuse(K,target, app.classes);
%                             [c,cm,ind,per] = confusion(t,o);
           end


        end

        % Value changed function: EucleadianSwitch
        function EucleadianSwitchValueChanged(app, event)
            app.eucleadian = app.EucleadianSwitch.Value;
            
        end

        % Value changed function: HzSwitch
        function HzSwitchValueChanged(app, event)
            app.first = app.HzSwitch.Value;
            
        end

        % Value changed function: HzSwitch_2
        function HzSwitch_2ValueChanged(app, event)
            app.second = app.HzSwitch_2.Value;
            
        end

        % Value changed function: HzSwitch_3
        function HzSwitch_3ValueChanged(app, event)
            app.third = app.HzSwitch_3.Value;
            
        end

        % Value changed function: HzSwitch_4
        function HzSwitch_4ValueChanged(app, event)
            app.fourth = app.HzSwitch_4.Value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EucleadianSwitch.Value= 'Off';
            app.HzSwitch.Value= 'Off';
            app.HzSwitch_2.Value= 'Off';
            app.HzSwitch_3.Value= 'Off';
            app.HzSwitch_4.Value= 'Off';
            app.RiemannSwitch.Value= 'Off';
            app.kullbackSwitch.Value= 'Off';
            app.ldSwitch.Value= 'Off';
            app.logeuclidSwitch.Value= 'Off';
            app.opttranspSwitch.Value= 'Off';
            
            app.riemann= 0;
            app.kullback= 0;
            app.ld= 0;
            app.logeuclid= 0;
            app.opttransp= 0;
            app.eucleadian= 0;
            
            app.first= 0;
            app.second= 0;
            app.third= 0;
            app.fourth= 0;
                        
            app.type= [];
            app.nbrClasses= [];
            app.hcpMean= [];
            app.hcp1Mean= [];
            app.classes= [];
            
            app.ofEpochsSlider.Value= 7;
            app.NumberEditField.Value= 7;
            app.WindowSpacingEditField.Value= 0.2;
            app.WindowLengthEditField.Value= 2.6;
        end

        % Value changed function: RiemannSwitch
        function RiemannSwitchValueChanged(app, event)
            app.riemann = app.RiemannSwitch.Value;
            
        end

        % Value changed function: WindowLengthEditField
        function WindowLengthEditFieldValueChanged(app, event)
            app.D = app.WindowLengthEditField.Value;
            
        end

        % Value changed function: WindowSpacingEditField
        function WindowSpacingEditFieldValueChanged(app, event)
            app.dn = app.WindowSpacingEditField.Value;
            
        end

        % Value changed function: kullbackSwitch
        function kullbackSwitchValueChanged(app, event)
            app.kullback = app.kullbackSwitch.Value;
            
        end

        % Value changed function: ldSwitch
        function ldSwitchValueChanged(app, event)
            app.ld = app.ldSwitch.Value;
            
        end

        % Value changed function: logeuclidSwitch
        function logeuclidSwitchValueChanged(app, event)
            app.logeuclid = app.logeuclidSwitch.Value;
            
        end

        % Value changed function: NumberEditField, ofEpochsSlider
        function ofEpochsSliderValueChanged(app, event)
            app.w = round(app.ofEpochsSlider.Value);
            app.NumberEditField.Value= app.w;
        end

        % Value changed function: opttranspSwitch
        function opttranspSwitchValueChanged(app, event)
            app.opttransp = app.opttranspSwitch.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ClassesPanel = uipanel(app.UIFigure);
            app.ClassesPanel.Title = 'Classes';
            app.HzSwitchLabel = uilabel(app.ClassesPanel);
            app.HzSwitchLabel.Text = '0 Hz';
            app.HzSwitch = uiswitch(app.ClassesPanel, 'slider');
            app.HzSwitch.ValueChangedFcn = createCallbackFcn(app, @HzSwitchValueChanged, true);
            app.HzSwitch_2Label = uilabel(app.ClassesPanel);
            app.HzSwitch_2Label.Text = '13 Hz';
            app.HzSwitch_2 = uiswitch(app.ClassesPanel, 'slider');
            app.HzSwitch_2.ValueChangedFcn = createCallbackFcn(app, @HzSwitch_2ValueChanged, true);
            app.HzSwitch_3Label = uilabel(app.ClassesPanel);
            app.HzSwitch_3Label.Text = '17 Hz';
            app.HzSwitch_3 = uiswitch(app.ClassesPanel, 'slider');
            app.HzSwitch_3.ValueChangedFcn = createCallbackFcn(app, @HzSwitch_3ValueChanged, true);
            app.HzSwitch_4Label = uilabel(app.ClassesPanel);
            app.HzSwitch_4Label.Text = '21 Hz';
            app.HzSwitch_4 = uiswitch(app.ClassesPanel, 'slider');
            app.HzSwitch_4.ValueChangedFcn = createCallbackFcn(app, @HzSwitch_4ValueChanged, true);
            app.GROUP31TextAreaLabel = uilabel(app.UIFigure);
            app.GROUP31TextAreaLabel.Text = 'GROUP 31';
            app.GROUP31TextArea = uitextarea(app.UIFigure);
            app.GROUP31TextArea.Value = {'BCI using SSVEP'};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OfflineAnalysisPanel = uipanel(app.UIFigure);
            app.OfflineAnalysisPanel.Title = 'Offline Analysis';
            app.DistanceMetricPanel = uipanel(app.OfflineAnalysisPanel);
            app.DistanceMetricPanel.Title = 'Distance Metric';
            app.RiemannSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.RiemannSwitchLabel.Text = 'Riemann';
            app.RiemannSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.RiemannSwitch.ValueChangedFcn = createCallbackFcn(app, @RiemannSwitchValueChanged, true);
            app.kullbackSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.kullbackSwitchLabel.Text = 'kullback';
            app.kullbackSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.kullbackSwitch.ValueChangedFcn = createCallbackFcn(app, @kullbackSwitchValueChanged, true);
            app.logeuclidSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.logeuclidSwitchLabel.Text = 'logeuclid';
            app.logeuclidSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.logeuclidSwitch.ValueChangedFcn = createCallbackFcn(app, @logeuclidSwitchValueChanged, true);
            app.opttranspSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.opttranspSwitchLabel.Text = 'opttransp';
            app.opttranspSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.opttranspSwitch.ValueChangedFcn = createCallbackFcn(app, @opttranspSwitchValueChanged, true);
            app.ldSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.ldSwitchLabel.Text = 'ld';
            app.ldSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.ldSwitch.ValueChangedFcn = createCallbackFcn(app, @ldSwitchValueChanged, true);
            app.EucleadianSwitchLabel = uilabel(app.DistanceMetricPanel);
            app.EucleadianSwitchLabel.Text = 'Eucleadian';
            app.EucleadianSwitch = uiswitch(app.DistanceMetricPanel, 'slider');
            app.EucleadianSwitch.ValueChangedFcn = createCallbackFcn(app, @EucleadianSwitchValueChanged, true);
            app.OnlineAnalysisPanel = uipanel(app.UIFigure);
            app.OnlineAnalysisPanel.Title = 'Online Analysis';
            app.WindowLengthEditFieldLabel = uilabel(app.OnlineAnalysisPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.OnlineAnalysisPanel, 'numeric');
            app.WindowLengthEditField.LowerLimitInclusive = 'off';
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditFieldValueChanged, true);
            app.WindowLengthEditField.Limits = [0 10];
            app.WindowLengthEditField.Value = 2.6;
            app.WindowSpacingEditFieldLabel = uilabel(app.OnlineAnalysisPanel);
            app.WindowSpacingEditFieldLabel.Text = 'Window Spacing';
            app.WindowSpacingEditField = uieditfield(app.OnlineAnalysisPanel, 'numeric');
            app.WindowSpacingEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSpacingEditFieldValueChanged, true);
            app.WindowSpacingEditField.Limits = [0 10];
            app.WindowSpacingEditField.Value = 0.2;
            app.NumberEditFieldLabel = uilabel(app.OnlineAnalysisPanel);
            app.NumberEditFieldLabel.Text = 'Number';
            app.NumberEditField = uieditfield(app.OnlineAnalysisPanel, 'numeric');
            app.NumberEditField.ValueChangedFcn = createCallbackFcn(app, @ofEpochsSliderValueChanged, true);
            app.NumberEditField.Limits = [0 100];
            app.ofEpochsSliderLabel = uilabel(app.OnlineAnalysisPanel);
            app.ofEpochsSliderLabel.Text = 'of Epochs';
            app.ofEpochsSlider = uislider(app.OnlineAnalysisPanel);
            app.ofEpochsSlider.Limits = [1 100];
            app.ofEpochsSlider.ValueChangedFcn = createCallbackFcn(app, @ofEpochsSliderValueChanged, true);
            app.ofEpochsSlider.Value = 1;
            app.AnalysisButtonGroup = uibuttongroup(app.UIFigure);
            app.AnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisButtonGroupSelectionChanged, true);
            app.AnalysisButtonGroup.Title = 'Analysis';
            app.OfflineButton = uitogglebutton(app.AnalysisButtonGroup);
            app.OfflineButton.Text = 'Offline';
            app.OfflineButton.Value = true;
            app.OnlineButton = uitogglebutton(app.AnalysisButtonGroup);
            app.OnlineButton.Text = 'Online';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
        end
    end

    methods (Access = public)

        % Construct app
        function app = group31FINAL

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end